classdef adminpasscode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EnterButton                  matlab.ui.control.Button
        EnterAdminPasscodeEditField  matlab.ui.control.EditField
        EnterAdminPasscodeEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        pass
        flag = 1;
    end
    
    methods (Access = public)
        
        function closepasscode(app)
            UIFigureCloseRequest(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            app.pass=app.EnterAdminPasscodeEditField.Value;
            if(strcmp(app.pass,'Pushan_Mukhopadhyay')==0)
                beep;
                msgbox('Wrong Passcode','Error','error');
                enablebuttons(app.CallingApp);
            else
               app.flag=0;
               callresult(app.CallingApp);
            end
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if(app.flag==1)
                enablebuttons(app.CallingApp);
            end
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EnterAdminPasscodeEditFieldLabel = uilabel(app.UIFigure);
            app.EnterAdminPasscodeEditFieldLabel.Text = 'Enter Admin Passcode';
            app.EnterAdminPasscodeEditField = uieditfield(app.UIFigure, 'text');
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adminpasscode(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end